classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SelectfolderButton              matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        ProcessButton                   matlab.ui.control.Button
        Minimumnucleussizepx2EditFieldLabel  matlab.ui.control.Label
        Minimumnucleussizepx2EditField  matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            filename = {'cells001.tif';'cells002.tif';'cells003.tif';'cells004.tif';'cells005.tif'};
            n_nuclei = [43;21;55;56;24];
            mean_area = [2649;4629;2689;3197;5419];
            tab = table(filename,n_nuclei,mean_area);
            set(app.UITable,'data',tab);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SelectfolderButton = uibutton(app.UIFigure, 'push');
            app.SelectfolderButton.Text = 'Select folder';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'filename'; 'n_nuclei'; 'mean_area'};
            app.UITable.RowName = {};
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.Minimumnucleussizepx2EditFieldLabel = uilabel(app.UIFigure);
            app.Minimumnucleussizepx2EditFieldLabel.Text = 'Minimum nucleus size (px^2)';
            app.Minimumnucleussizepx2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Minimumnucleussizepx2EditField.Value = 500;
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end